classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        ChooseCSVFileButton  matlab.ui.control.Button
        UIAxes               matlab.ui.control.UIAxes
        SliderLabel          matlab.ui.control.Label
        Slider               matlab.ui.control.Slider
        Button               matlab.ui.control.Button
        EditFieldLabel       matlab.ui.control.Label
        EditField            matlab.ui.control.NumericEditField
        EditField2Label      matlab.ui.control.Label
        EditField2           matlab.ui.control.NumericEditField
        ItiswhatitisLabel    matlab.ui.control.Label
        DropDownLabel        matlab.ui.control.Label
        DropDown             matlab.ui.control.DropDown
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function yInputValueChanged(app, event)
            
            yValue = double(app.yInput.Value);
            
        end

        % Callback function
        function xInputValueChanged(app, event)
            xValue = double(app.xInput.Value);
            
        end

        % Button pushed function: ChooseCSVFileButton
        function ChooseCSVFileButtonPushed(app, event)
            [file,path] = uigetfile('*.csv');
            data = readtable(file);
            
            if(table2array(data(3,2)) > 0)
                latitude = table2array(data(:,2)); longitude = table2array(data(:,3));
            else
                latitude = table2array(data(:,3)); longitude = table2array(data(:,2));
            end
            
            pixelLong = gpX2Pixel(longitude);
            pixelLat = gpY2Pixel(latitude);
            
            xl = [0,1653];
            yl = [0,1559];
            im = imread('CampusMap.png');
            h = image(xl, yl, im);impixelinfo,
            ax = gca;
            ax.YDir = 'reverse';
            uistack(h, 'bottom');
            
            
            x = pixelLong;
            y = pixelLat;
            
            hold on;
            plot(x,y, 'o', 'MarkerFaceColor', 'r', 'MarkerSize', 6);
            grid on;
            
            function [y] = gpY2Pixel(lat) 
            parentGPSY = 38.546339;
            latitudeIncrementPerPixel = 0.0000133;
            y = abs((parentGPSY)-(lat))/ latitudeIncrementPerPixel;
            end
            
            function [x] = gpX2Pixel(long)
            parentGPSX = -121.768097;
            longitudeIncrementPerPixel = 0.0000167;
            x = abs((parentGPSX)-(long))/longitudeIncrementPerPixel;
            end
        end

        % Callback function
        function CalibrationpointsButtonPushed(app, event)
       
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ChooseCSVFileButton = uibutton(app.UIFigure, 'push');
            app.ChooseCSVFileButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseCSVFileButtonPushed, true);
            app.ChooseCSVFileButton.Text = 'Choose CSV File';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Button = uibutton(app.UIFigure, 'push');
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField2Label = uilabel(app.UIFigure);
            app.EditField2Label.Text = 'Edit Field2';
            app.EditField2 = uieditfield(app.UIFigure, 'numeric');
            app.ItiswhatitisLabel = uilabel(app.UIFigure);
            app.ItiswhatitisLabel.Text = 'It is what it is';
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end